classdef SoFoCo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        pb_analyze                      matlab.ui.control.Button
        ef_foci_intensity               matlab.ui.control.NumericEditField
        MaximalradiusoffociLabel_2      matlab.ui.control.Label
        ef_foci_radius                  matlab.ui.control.NumericEditField
        MaximalradiusoffociLabel        matlab.ui.control.Label
        lb_foci                         matlab.ui.control.Label
        pb_validate_mask                matlab.ui.control.Button
        pb_preview_mask                 matlab.ui.control.Button
        ef_no_of_closings               matlab.ui.control.NumericEditField
        NumberofclosingsEditFieldLabel  matlab.ui.control.Label
        lb_secondary_mask               matlab.ui.control.Label
        ef_kernel_size                  matlab.ui.control.NumericEditField
        MinimalnucleiradiuspixelEditFieldLabel  matlab.ui.control.Label
        dd_threshold                    matlab.ui.control.DropDown
        ThresholdprocedureDropDownLabel  matlab.ui.control.Label
        pb_validate_threshold           matlab.ui.control.Button
        pb_view_ax2                     matlab.ui.control.Button
        pb_view_ax1                     matlab.ui.control.Button
        lb_primary_mask                 matlab.ui.control.Label
        load                            matlab.ui.container.ButtonGroup
        pb_load_foci                    matlab.ui.control.Button
        pb_load_nuclei                  matlab.ui.control.Button
        pb_load_rgb                     matlab.ui.control.Button
        dd_foci_ch                      matlab.ui.control.DropDown
        FociChannelDropDownLabel        matlab.ui.control.Label
        dd_nuclei_ch                    matlab.ui.control.DropDown
        NucleiChannelDropDownLabel      matlab.ui.control.Label
        rb_gray                         matlab.ui.control.RadioButton
        rb_rgb                          matlab.ui.control.RadioButton
        ax1                             matlab.ui.control.UIAxes
        ax2                             matlab.ui.control.UIAxes
    end

    %TODO Estaría bien poner una imagen de cargando cada vez que se esté
    %ejecutando algo y demás... algo así como enable/disable antes de cada
    %callback, sin más

    methods (Access = private)
        
        function f = adpmedian(app, g, Smax)


    %ADPMEDIAN Perform adaptive median filtering. The function was taken
    %from Gonzalez RC, Woods RE, Eddins SLU. Digital Image Processing Using
    %MATLAB. Pearson Prentice Hall; 2004.

    %   F = ADPMEDIAN(G, SMAX) performs adaptive median filtering of
    %   image G.  The median filter starts at size 3-by-3 and iterates up   
    %   to size SMAX-by-SMAX. SMAX must be an odd integer greater than 1.
    %   SMAX must be an odd, positive integer greater than 1.

            if (Smax <= 1) || (Smax/2 == round(Smax/2)) || (Smax ~= round(Smax))
                error('SMAX must be an odd integer > 1.')
            end
            [M, N] = size(g);
            % Initial setup.
            f = g;
            f(:) = 0;
            alreadyProcessed = false(size(g));
            % Begin filtering.
            for k = 3:2:Smax
               zmin = ordfilt2(g, 1, ones(k, k), 'symmetric');
               zmax = ordfilt2(g, k * k, ones(k, k), 'symmetric');
               zmed = medfilt2(g, [k k], 'symmetric');
               processUsingLevelB = (zmed > zmin) & (zmax > zmed) & ...
                   ~alreadyProcessed; 
               zB = (g > zmin) & (zmax > g);
               outputZxy  = processUsingLevelB & zB;
               outputZmed = processUsingLevelB & ~zB;
               f(outputZxy) = g(outputZxy);
               f(outputZmed) = zmed(outputZmed);
               alreadyProcessed = alreadyProcessed | processUsingLevelB;    
               if all(alreadyProcessed(:))
                  break;
               end
            end

    % Output zmed for any remaining unprocessed pixels. Note that this
    % zmed was computed using a window of size Smax-by-Smax, which is
    % the final value of k in the loop.
        f(~alreadyProcessed) = zmed(~alreadyProcessed);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: load
        function loadSelectionChanged(app, event)
            selectedButton = app.load.SelectedObject;
            switch selectedButton.Text
                case 'RGB Image'
                    app.pb_load_nuclei.Enable='off';
                    app.pb_load_foci.Enable='off';
                    app.dd_nuclei_ch.Enable='on';
                    app.dd_foci_ch.Enable='on';
                    app.pb_load_rgb.Enable='on';
                case 'Grayscale Image'
                    app.pb_load_nuclei.Enable='on';
                    app.pb_load_foci.Enable='on';
                    app.dd_nuclei_ch.Enable='off';
                    app.dd_foci_ch.Enable='off';
                    app.pb_load_rgb.Enable='off';
                    %TODO Hacer que esto funcione en general
            end
        end

        % Button pushed function: pb_load_rgb
        function pb_load_rgbButtonPushed(app, event)
            global handles
            % Get image path and load
            [fname,fpath]=uigetfile('\*.tif;*.jpg;*.png;*.*','File Selector');
            fullname=strcat(fpath,fname);
            handles.im=imread(fullname);
            % Get channels and represent original images
            if app.dd_nuclei_ch.Value(1)=='B'
                handles.nch=3;
            elseif app.dd_nuclei_ch.Value(1)=='R'
                handles.nch=1;
            elseif app.dd_nuclei_ch.Value(1)=='G'
                handles.nch=2;
            end
            if app.dd_foci_ch.Value(1)=='G'
                handles.fch=2;
            elseif app.dd_foci_ch.Value(1)=='R'
                handles.fch=1;
            elseif app.dd_foci_ch.Value(1)=='B'
                handles.fch=3;
            end
            handles.im_nuc=handles.im(:,:,handles.nch);
            handles.im_foc=handles.im(:,:,handles.fch);            
            imshow(handles.im_nuc,'Parent',app.ax1)
            app.ax1.Visible='on';
            app.pb_view_ax1.Visible='on';
            imshow(handles.im_foc,'Parent',app.ax2)
            app.ax2.Visible='on';
            app.pb_view_ax2.Visible='on';
            % Enable next step
            app.dd_threshold.Enable='on';
            app.pb_validate_threshold.Enable='on';
            %TODO Hacer que cuando se haya cargado la imagen, se desactiven
            %los demás botones para no cambiar y no liarla
        end

        % Button pushed function: pb_view_ax1
        function pb_view_ax1ButtonPushed(app, event)
            %TODO Poner para que se vea el ax1 en ventana independiente
            warndlg('Coming soon', 'TO DO')
        end

        % Button pushed function: pb_view_ax2
        function pb_view_ax2ButtonPushed(app, event)
            %TODO Poner para que se vea el ax2 en ventana independiente
            warndlg('Coming soon', 'TO DO')
        end

        % Button pushed function: pb_load_nuclei
        function pb_load_nucleiButtonPushed(app, event)
            %TODO Cargar únicamente el canal del núcleo, poner que dé error
            %si las imágenes son de más de 3 canal, etc...
            warndlg('Coming soon', 'TO DO')
        end

        % Button pushed function: pb_load_foci
        function pb_load_fociButtonPushed(app, event)
            %TODO Cargar únicamente el canal de foci, poner que dé error
            %si las imágenes son de más de 1 canal, etc...
            warndlg('Coming soon', 'TO DO')
        end

        % Value changed function: dd_threshold
        function dd_thresholdValueChanged(app, event)
            global handles
            handles.th1_method = app.dd_threshold.Value;
            if strcmp(handles.th1_method,'Otsu')
                handles.im_nucth1=imbinarize(handles.im_nuc,'global');
                title(app.ax2,'Mask preview');
                imshow(handles.im_nucth1,'Parent',app.ax2)
            elseif strcmp(handles.th1_method,'Adaptive')
                handles.im_nucth1=imbinarize(handles.im_nuc,'adaptive');
                title(app.ax2,'Mask preview');
                imshow(handles.im_nucth1,'Parent',app.ax2)
            elseif strcmp(handles.th1_method,'Select...')
                warndlg('Please, select a thresholding method','Warning')
            else
                %TODO añadir otros métodos de umbralización
                warndlg('Coming soon','TO DO')
            end
        end

        % Button pushed function: pb_validate_threshold
        function pb_validate_thresholdButtonPushed(app, event)
            global handles
            handles.im_nucth1filt=adpmedian(app,handles.im_nucth1,3);
            handles.im_nucmask1=imfill(handles.im_nucth1filt,'holes');
            title(app.ax2,'Foci');
            imshow(handles.im_foc,'Parent',app.ax2)
            title(app.ax1,'Nuclei (mask 1)')
            imshow(handles.im_nucmask1,'Parent',app.ax1)
            app.ef_kernel_size.Enable='on';
            app.ef_no_of_closings.Enable='on';
            app.pb_preview_mask.Enable='on';
        end

        % Button pushed function: pb_preview_mask
        function pb_preview_maskButtonPushed(app, event)
            global handles
            handles.k_size=app.ef_kernel_size.Value;
            handles.no_of_closings=app.ef_no_of_closings.Value;
            se=strel('disk',handles.k_size); 
            handles.im_eroded=imerode(handles.im_nucmask1,se);
            handles.im_reconstructed=imreconstruct(handles.im_eroded,handles.im_nucmask1);
            looped=handles.im_reconstructed;
            for i=1:handles.no_of_closings
                looped=imdilate(looped,ones(3));
            end
            looped=imfill(looped,'holes');
            for i=1:handles.no_of_closings
                looped=imerode(looped,ones(3));
            end
            handles.im_nucmask2=looped;
            title(app.ax2,'Nuclei (mask 2)')
            imshow(handles.im_nucmask2,'Parent',app.ax2)
            app.pb_validate_mask.Enable='on';
        end

        % Button pushed function: pb_validate_mask
        function pb_validate_maskButtonPushed(app, event)
            global handles
            title(app.ax2,'Foci');
            imshow(handles.im_foc,'Parent',app.ax2)

% % % % %             % ESTO ES PARA HACER EL WATERSHED COMO LO HACEN ELLOS
% % % % %             % CON IMAGEJ. TAMPOCO ME GUSTA CÓMO SALE, ASÍ QUE LO
% % % % %             % VOY A CUANTIFICAR POR DENSIDAD CELULAR
% % % % %             % Watershed in imageJ
% % % % %             global imagej
% % % % %             imagej.ij='C:\Program Files\MATLAB\R2022a\java\jar\ij.jar';
% % % % %             imagej.mij='C:\Program Files\MATLAB\R2022a\java\jar\mij.jar';
% % % % %             javaaddpath(imagej.ij)
% % % % %             javaaddpath(imagej.mij)
% % % % %             MIJ.start
% % % % %             MIJ.createImage(double(handles.im_nucmask2))
% % % % %             ij.IJ.run('Make Binary', 'thresholded remaining black');
% % % % %             ij.IJ.run('Watershed');
% % % % %             handles.im_water=MIJ.getCurrentImage();
% % % % %             handles.im_nuclast=logical(handles.im_water);


%             TODO: perform watershed in matlab
%             openclose=imclose(imopen(handles.im_nucmask2,ones(200,200)),ones(200,200));
%             dist=bwdist(~openclose);
%             water=watershed(-dist);
%             frontiers=water==0;
%             handles.im_nucwater=handles.im_nucmask2.*~frontiers;
%             handles.im_nuclast=imclearborder(handles.im_nucwater);

            title(app.ax1,'Nuclei (mask 2)')
            imshow(handles.im_nuclast,'Parent',app.ax1)
            app.ef_foci_intensity.Enable='on';
            app.ef_foci_radius.Enable='on';
            app.pb_analyze.Enable='on';
        end

        % Button pushed function: pb_analyze
        function pb_analyzeButtonPushed(app, event)
            global handles
            figure, imshow(handles.im)
            hold on
            handles.im_focifilt=adpmedian(app,handles.im_foc,3);
            handles.focrad=app.ef_foci_radius.Value;
            handles.focint=app.ef_foci_intensity.Value;
% % % %             TODO % Splitting the image with nuclei and foci on RGB channels
% % % %             im1=im(:,:,1);
% % % %             im2=im(:,:,2);
% % % %             im3=im(:,:,3);
% % % %             
% % % %             % Adding a nuclei perimeter to each RGB channel
% % % %             im1(perim)=1;
% % % %             im2(perim)=1;
% % % %             im3(perim)=1;
% % % %             
% % % %             % Merging RGB channels back to one image
% % % %             imperim=im;
% % % %             imperim(:,:,1)=im1;
% % % %             imperim(:,:,2)=im2;
% % % %             imperim(:,:,3)=im3;
            [handles.im_labeled,n]=bwlabeln(handles.im_nuclast);
            handles.foc_int{n}=[];
            handles.nuc_num{n}=[];
            handles.foc_num{n}=[];
            for nuclei=1:n
                [r,c,s]=find(handles.im_labeled==nuclei);
                [x,y]=size(handles.im_labeled);
                S = sparse(r,c,s,x,y);
                g=handles.im_foc(S);
                handles.foc_int{nuclei}=mean(g);
                
                % Creating an image mask with the single nucleus 'it'
                distnucl=false(size(handles.im_labeled));
                distnucl(S)=1;

                handles.nuc_num{nuclei}=sum(distnucl(:));
                
                % Applying the nuclei mask for the nucleus 'it' to the foci image
                gamM=handles.im_foc(distnucl);
                
                % Cropping the nucleus 'it' from the foci image
                ymin=min(r);
%                 ymax=max(r);
                xmin=min(c);
%                 xmax=max(c);
                width=max(c)-min(c);
                height=max(r)-min(r);
                rect=[xmin ymin width height];
                distnuclcrop = imcrop(gamM, rect);
                
                % Applying adaptive median filter of the size 3
                distnuclcrop=adpmedian(app,distnuclcrop,3);

                % Estimating the foci image background by morphological
                % opening. For the morphological opening we used a
                % structuring element disk. The radius of the disk is a
                % user-defined parameter, which designates the maximal 
                % foci radius in pixels
                background = imopen(distnuclcrop,strel('disk',handles.focrad));

                % Subtracting the image background
                distnuclcrop=distnuclcrop-background;
                
                % Quantifying the Otsu's threshold
                level = graythresh(distnuclcrop);

                % Applying H-maxima transform with the Otsu's threshold as a parameter
                BW = imextendedmax(distnuclcrop,level);
                
                % Applying obtained foci mask to the foci image with subtracted background
                locmax=distnuclcrop(BW);
                
                % Thresholding the obtained image with the user-defined parameter 'Minimal
                % intensity of foci'
                locmaxthresh=locmax>handles.focint;
                
                % Counting and labeling elements of the obtained foci mask
                [L1,n1]=bwlabel(locmaxthresh);
                
                % Putting the number of detected foci per nucleus in arrays with
                % quantification results
                handles.foc_num{nuclei}=n1;

                
                % Putting frames over detected foci
                for k=1:n1
                    [r,c,~]=find(L1==k);
                    ymin1=min(r);
                    xmin1=min(c);
                    width1=max(c)-min(c);
                    height1=max(r)-min(r);
                    rect1=[xmin+xmin1-2 ymin+ymin1-2 width1+2 height1+2];
                    rectangle(app.ax1,'Position',rect1, 'LineWidth',1, 'EdgeColor','r');
                end
                
                hold off
                % Saving arrays with foci numbers per nucleus and mean fluorescent intensity
                % per nucleus in handles structure
%                 handles.fociArr{1}=foci;
%                 handles.fociIntArr{jit}=fociInt;

            end
            x=1
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax2 = uiaxes(app.UIFigure);
            title(app.ax2, 'Foci')
            app.ax2.Visible = 'off';
            app.ax1 = uiaxes(app.UIFigure);
            title(app.ax1, 'Nuclei')
            app.ax1.Visible = 'off';
            app.load = uibuttongroup(app.UIFigure);
            app.load.SelectionChangedFcn = createCallbackFcn(app, @loadSelectionChanged, true);
            app.load.Title = 'Image loading';
            app.rb_rgb = uiradiobutton(app.load);
            app.rb_rgb.Text = 'RGB Image';
            app.rb_rgb.Value = true;
            app.rb_gray = uiradiobutton(app.load);
            app.rb_gray.Text = 'Grayscale Image';
            app.NucleiChannelDropDownLabel = uilabel(app.load);
            app.NucleiChannelDropDownLabel.Text = 'Nuclei Channel';
            app.dd_nuclei_ch = uidropdown(app.load);
            app.dd_nuclei_ch.Items = {'Red', 'Green', 'Blue'};
            app.dd_nuclei_ch.Value = 'Blue';
            app.FociChannelDropDownLabel = uilabel(app.load);
            app.FociChannelDropDownLabel.Text = 'Foci Channel';
            app.dd_foci_ch = uidropdown(app.load);
            app.dd_foci_ch.Items = {'Red', 'Green', 'Blue'};
            app.dd_foci_ch.Value = 'Green';
            app.pb_load_rgb = uibutton(app.load, 'push');
            app.pb_load_rgb.ButtonPushedFcn = createCallbackFcn(app, @pb_load_rgbButtonPushed, true);
            app.pb_load_rgb.Text = 'Load image';
            app.pb_load_nuclei = uibutton(app.load, 'push');
            app.pb_load_nuclei.ButtonPushedFcn = createCallbackFcn(app, @pb_load_nucleiButtonPushed, true);
            app.pb_load_nuclei.Enable = 'off';
            app.pb_load_nuclei.Text = 'Load Nuclei';
            app.pb_load_foci = uibutton(app.load, 'push');
            app.pb_load_foci.ButtonPushedFcn = createCallbackFcn(app, @pb_load_fociButtonPushed, true);
            app.pb_load_foci.Enable = 'off';
            app.pb_load_foci.Text = 'Load Foci';
            app.lb_primary_mask = uilabel(app.UIFigure);
            app.lb_primary_mask.Text = 'Primary mask';
            app.pb_view_ax1 = uibutton(app.UIFigure, 'push');
            app.pb_view_ax1.ButtonPushedFcn = createCallbackFcn(app, @pb_view_ax1ButtonPushed, true);
            app.pb_view_ax1.Visible = 'off';
            app.pb_view_ax1.Text = 'View in new window';
            app.pb_view_ax2 = uibutton(app.UIFigure, 'push');
            app.pb_view_ax2.ButtonPushedFcn = createCallbackFcn(app, @pb_view_ax2ButtonPushed, true);
            app.pb_view_ax2.Visible = 'off';
            app.pb_view_ax2.Text = 'View in new window';
            app.pb_validate_threshold = uibutton(app.UIFigure, 'push');
            app.pb_validate_threshold.ButtonPushedFcn = createCallbackFcn(app, @pb_validate_thresholdButtonPushed, true);
            app.pb_validate_threshold.Enable = 'off';
            app.pb_validate_threshold.Text = 'Validate thresholding';
            app.ThresholdprocedureDropDownLabel = uilabel(app.UIFigure);
            app.ThresholdprocedureDropDownLabel.Text = 'Threshold procedure';
            app.dd_threshold = uidropdown(app.UIFigure);
            app.dd_threshold.Items = {'Select...', 'Otsu', 'Adaptive', 'Others...'};
            app.dd_threshold.ValueChangedFcn = createCallbackFcn(app, @dd_thresholdValueChanged, true);
            app.dd_threshold.Enable = 'off';
            app.dd_threshold.Value = 'Select...';
            app.MinimalnucleiradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.MinimalnucleiradiuspixelEditFieldLabel.Text = 'Minimal nuclei radius (pixel)';
            app.ef_kernel_size = uieditfield(app.UIFigure, 'numeric');
            app.ef_kernel_size.Enable = 'off';
            app.ef_kernel_size.Value = 40;
            app.lb_secondary_mask = uilabel(app.UIFigure);
            app.lb_secondary_mask.Text = 'Secondary mask';
            app.NumberofclosingsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofclosingsEditFieldLabel.Text = 'Number of closings';
            app.ef_no_of_closings = uieditfield(app.UIFigure, 'numeric');
            app.ef_no_of_closings.Enable = 'off';
            app.ef_no_of_closings.Value = 3;
            app.pb_preview_mask = uibutton(app.UIFigure, 'push');
            app.pb_preview_mask.ButtonPushedFcn = createCallbackFcn(app, @pb_preview_maskButtonPushed, true);
            app.pb_preview_mask.Enable = 'off';
            app.pb_preview_mask.Text = 'Preview secondary mask';
            app.pb_validate_mask = uibutton(app.UIFigure, 'push');
            app.pb_validate_mask.ButtonPushedFcn = createCallbackFcn(app, @pb_validate_maskButtonPushed, true);
            app.pb_validate_mask.Enable = 'off';
            app.pb_validate_mask.Text = 'Validate secondary mask';
            app.lb_foci = uilabel(app.UIFigure);
            app.lb_foci.Text = 'Foci counting';
            app.MaximalradiusoffociLabel = uilabel(app.UIFigure);
            app.MaximalradiusoffociLabel.Text = 'Maximal radius of foci (pixel)';
            app.ef_foci_radius = uieditfield(app.UIFigure, 'numeric');
            app.ef_foci_radius.Enable = 'off';
            app.ef_foci_radius.Value = 3;
            app.MaximalradiusoffociLabel_2 = uilabel(app.UIFigure);
            app.MaximalradiusoffociLabel_2.Text = 'Minimal intensity of foci';
            app.ef_foci_intensity = uieditfield(app.UIFigure, 'numeric');
            app.ef_foci_intensity.Enable = 'off';
            app.ef_foci_intensity.Value = 0.09;
            app.pb_analyze = uibutton(app.UIFigure, 'push');
            app.pb_analyze.ButtonPushedFcn = createCallbackFcn(app, @pb_analyzeButtonPushed, true);
            app.pb_analyze.Enable = 'off';
            app.pb_analyze.Text = 'Analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoFoCo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end